classdef ShepherdScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        shepherd                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OutputFolderLabel           matlab.ui.control.Label
        TDSROOTFolderLabel          matlab.ui.control.Label
        StartMonitoringSwitch       matlab.ui.control.Switch
        StartMonitoringSwitchLabel  matlab.ui.control.Label
        DCMROOTFolderLabel          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        OutputFolder                matlab.ui.control.EditField
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        RESULTSLabel                matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        TRAJECTORYFILESFOUNDLabel   matlab.ui.control.Label
        PLANFILESFOUNDLabel         matlab.ui.control.Label
        Status_2                    matlab.ui.control.TextArea
        TDSFolder                   matlab.ui.control.EditField
        dcmFolder                   matlab.ui.control.EditField
        Status                      matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        p = '' % this is the parallel pool
        fileObj1
        fileObj2
    end
    
    methods (Access = public)
        
        function FileWatch(app, pathToWatch)
            app.Status.Value = strcat(datestr(datetime('now')),': ', 'Creating Watcher...',pathToWatch);
            app.fileObj1 = System.IO.FileSystemWatcher(pathToWatch)
            app.fileObj1.IncludeSubdirectories = true;
            app.fileObj1.EnableRaisingEvents = true;
            %fileObj1.Filter = '*.dcm'
            changeListener = addlistener(app.fileObj1, 'Created', @onChange); 
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), disp('Monitor Died...'))

             function onChange(~, evt)
                fileName = char(evt.FullPath.ToString());
                disp(fileName);
                binTF = (strfind(fileName(1,:),'RP'));
                disp(binTF);
                if ~isempty(binTF) > 0 %found a bin file
                    fileName = fileName(1,:);
                    
                        %beep();
                        %call the function
                        disp(fileName)
                        current_message = app.Status.Value
                        new_message = {[datestr(datetime('now')),': ', 'Filename: ',fileName]}
                        app.Status.Value = [current_message; new_message]
                        TableData = app.UITable.Data %current data
                        app.UITable.Data = [TableData; {datestr(datetime('now')), fileName, 'RTPLAN', 'TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        MLC_CHECK(app, fileName)
                    
        
               end
            end %onChange
    %FileWatch 
        end
    %this is the binary tracj logs
        function FileWatch2(app, pathToWatch2)
            app.Status_2.Value = strcat(datestr(datetime('now')),': ', 'Creating Watcher...',pathToWatch2);
            app.fileObj2 = System.IO.FileSystemWatcher(pathToWatch2)
            app.fileObj2.IncludeSubdirectories = true;
            app.fileObj2.EnableRaisingEvents = true;
            app.fileObj2.Filter = '*.bin';
            changeListener2 = addlistener(app.fileObj2, 'Renamed', @onChange2); 
            %destroyedListener = addlistener(fileObj,'ObjectBeingDestroyed',@(src,evt), disp('Monitor Died...'))

             function onChange2(~, evt)
                fileNameTDS = char(evt.FullPath.ToString());
                disp(fileNameTDS);
                binTFTDS = (strfind(fileNameTDS(1,:),'.bin'));
                disp(binTFTDS);
                if ~isempty(binTFTDS) > 0 %found a bin file
                    fileNameTDS = fileNameTDS(1,:);
                    if isempty(strfind(fileNameTDS(1,:),'KV'))
                        %beep();
                        %call the function
                        disp(fileNameTDS)
                        current_message2 = app.Status_2.Value;
                        new_message2 = {[datestr(datetime('now')),': ', 'Filename: ',fileNameTDS]};
                        app.Status_2.Value = [current_message2; new_message2];
                        scroll(app.Status_2,'bottom')
                        TableData = app.UITable.Data %current data
                        app.UITable.Data = [TableData; {datestr(datetime('now')), fileNameTDS, 'VARIAN TLOG','TEST'}]
                        scroll(app.UITable,'bottom')
                        %eFluenceQA_MMLC(fileName)
                        %app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring found a new file.');
                        %app.Status.Value = cellstr(struct2cell(dicominfo(fileName)))
                        %return                    %run the check
                        TLOG_CHECK(app, fileNameTDS)
                    end
        
               end
            end %onChange
    %FileWatch 
        end

        function TLOG_CHECK(app, fileNameTDS)
        
            VarianTLog_MLC(fileNameTDS)
        end

        
        function MLC_CHECK(app, fileName)
            
            %this function operates if there is an MLC detected
            OutFolder = app.OutputFolder.Value;
            fInfo = dicominfo(fileName)
            Modality = fInfo.Modality %'RTPLAN'
            if strcmp(Modality,'RTPLAN') == 0
                %app.Status.Value = strcat(datestr(datetime('now')),': ', 'Not an RTPLAN... returning to monitoring');
                return
            else
                %pp.Status.Value = strcat(datestr(datetime('now')),': ', 'It is an RTPLAN proceeding to process...');
                %get all the metadata
                try
                    Plan.Filename = fInfo.Filename;
                    Plan.FileModDate = fInfo.FileModDate;
                    Plan.InstanceCreationDate = fInfo.InstanceCreationDate; 
                    Plan.InstanceCreationTime = fInfo.InstanceCreationTime; 
                    Plan.SOPClassUID = fInfo.SOPClassUID;
                    Plan.SOPInstanceUID = fInfo.SOPInstanceUID;
                    Plan.StudyDate = fInfo.StudyDate;
                    Plan.StudyTime = fInfo.StudyTime;
                    Plan.SeriesTime = fInfo.SeriesTime;
                    Plan.Manufacturer = fInfo.Manufacturer; %Varian or Elekta
                    Plan.InstitutionName = fInfo.InstitutionName; %Facility GC
                    Plan.PhysiciansOfRecord = fInfo.PhysiciansOfRecord.FamilyName; %RO
                    Plan.OperatorsName = fInfo.OperatorsName; %planner
                    Plan.SeriesDescription = fInfo.SeriesDescription;
                    Plan.ManufacturerModelName = fInfo.ManufacturerModelName;
                    Plan.PatientFirstName = fInfo.PatientName.GivenName;
                    Plan.PatientLastName = fInfo.PatientName.FamilyName;
                    Plan.PatientID = fInfo.PatientID;
                    Plan.PatientDOB = fInfo.PatientBirthDate;
                    Plan.PatietnSex = fInfo.PatientSex;
                    Plan.RTPlanLabel = fInfo.RTPlanLabel;
                    Plan.RTPlanDate = fInfo.RTPlanDate;
                    Plan.ApprovalStatus = fInfo.ApprovalStatus;
                catch
                    %Plan.Filename = 'anon';
                    Plan.FileModDate = 'anon';
                    Plan.InstanceCreationDate = 'anon';
                    Plan.InstanceCreationTime = 'anon';
                    Plan.SOPClassUID = 'anon';
                    Plan.SOPInstanceUID = 'anon';
                    Plan.StudyDate = 'anon';
                    Plan.StudyTime = 'anon';
                    Plan.SeriesTime = 'anon';
                    Plan.Manufacturer = fInfo.Manufacturer; %Varian or Elekta
                    Plan.InstitutionName = 'anon';%Facility GC
                    Plan.PhysiciansOfRecord = 'anon'; %RO
                    Plan.OperatorsName = 'anon'; %planner
                    Plan.SeriesDescription ='anon';
                    Plan.ManufacturerModelName = 'anon';
                    Plan.PatientFirstName = 'anon';
                    Plan.PatientLastName = 'anon';
                    Plan.PatientID = fInfo.PatientID;
                    Plan.PatientDOB = fInfo.PatientBirthDate;
                    Plan.PatietnSex = fInfo.PatientSex;
                    Plan.RTPlanLabel = fInfo.RTPlanLabel;
                    Plan.RTPlanDate = fInfo.RTPlanDate;
                    Plan.ApprovalStatus = fInfo.ApprovalStatus;
                end
                %app.Status.Value = strcat(datestr(datetime('now')),': ', 'New File Detected:',fileName);
                %Plan.ReviewDate = fInfo.ReviewDate;
                current_message = app.Status.Value

                new_message = {[datestr(datetime('now')),': ', 'Filename: ',Plan.Filename];
                            [datestr(datetime('now')),': ', 'Patient ID: ',Plan.PatientID];
                            [datestr(datetime('now')),': ', 'Plan Name: ',Plan.RTPlanLabel];
                            [datestr(datetime('now')),': ', 'Plan Date: ',Plan.RTPlanDate];
                            [datestr(datetime('now')),': ', 'Approval Status: ',Plan.ApprovalStatus];
                            [datestr(datetime('now')),': ', 'Manufacturer: ', Plan.Manufacturer]
                            ['---------------------','--------------------','-----------------']}

                app.Status.Value = [current_message; new_message]
                scroll(app.Status,'bottom')
                %app.Status.Value = new_message;
                %notify the messenger
                
               
                if contains(Plan.Manufacturer,'VARIAN','IgnoreCase',true)
                    disp('Varian')
                    parfeval(app.p,@eFluenceQA_MMLC,0,fileName,OutFolder)
                elseif contains(Plan.Manufacturer,'Elekta','IgnoreCase',true) || contains(Plan.Manufacturer,'CMS','IgnoreCase',true) || contains(Plan.Manufacturer,'Monaco','IgnoreCase',true)
                    disp('Elekta')
                    parfeval(app.p,@eFluenceQA_Agility,0,fileName,OutFolder)
                end
            end
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartMonitoringSwitch
        function StartMonitoringSwitchValueChanged(app, event)
            value = app.StartMonitoringSwitch.Value;
            
            %set the numner of workers
            %testing parallel computing methods
            %update the handles
            
            if strcmp(value,'On')
                p = parpool(2); 
                app.p = p; %update the app properties
                pathToWatch = app.dcmFolder.Value  %get the plan path
                disp(pathToWatch)
                pathToWatch2 = app.TDSFolder.Value %get the TDS path
                app.Lamp.Color = 'green';
                app.Lamp_2.Color = 'green';
                app.StartMonitoringSwitchLabel.Text = 'Monitoring...';
                app.Status.Value = strcat(datestr(datetime('now')),': ', ' Checking for new plan files...');
                app.Status.Value = strcat(datestr(datetime('now')),': ', ' Looking in folder:', pathToWatch);
                app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Checking for new trajectory files...');
                app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Looking in folder:', pathToWatch2);
                FileWatch(app,pathToWatch);
                FileWatch2(app,pathToWatch2);
            else
                app.Lamp.Color = 'red';
                app.Lamp_2.Color = 'red';
                app.Status.Value = strcat(datestr(datetime('now')),': ', ' Monitoring Stopped.');
                app.Status_2.Value = strcat(datestr(datetime('now')),': ', ' Monitoring Stopped.');
                app.StartMonitoringSwitchLabel.Text = 'Start Monitoring';
                delete fileObj1
                delete fileObj2
                delete(gcp('nocreate'))
            end


        end

        % Close request function: shepherd
        function ShpeherdScanCloseRequest(app, event)
            delete fileObj1
            delete fileObj2
            delete(gcp('nocreate'))
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.shepherd.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {812, 812};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {128, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.shepherd = uifigure('Visible', 'off');
            app.shepherd.Name = 'ShepherdScan';
            app.shepherd.Icon = fullfile(pathToMLAPP, 'Logos', 'ICON.png');
            app.shepherd.CloseRequestFcn = createCallbackFcn(app, @ShpeherdScanCloseRequest, true);
            app.shepherd.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.shepherd.Tag = 'ShepherdScan';
            app.GridLayout = uigridlayout(app.shepherd);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DCMROOTFolderLabel = uilabel(app.LeftPanel);
            app.DCMROOTFolderLabel.Text = 'DCM ROOT Folder:';
            app.StartMonitoringSwitchLabel = uilabel(app.LeftPanel);
            app.StartMonitoringSwitchLabel.Text = 'Start Monitoring';
            app.StartMonitoringSwitch = uiswitch(app.LeftPanel, 'slider');
            app.StartMonitoringSwitch.ValueChangedFcn = createCallbackFcn(app, @StartMonitoringSwitchValueChanged, true);
            app.TDSROOTFolderLabel = uilabel(app.LeftPanel);
            app.TDSROOTFolderLabel.Text = 'TDS ROOT Folder:';
            app.OutputFolderLabel = uilabel(app.LeftPanel);
            app.OutputFolderLabel.Text = 'Output Folder:';
            app.RightPanel = uipanel(app.GridLayout);
            app.Status = uitextarea(app.RightPanel);
            app.Status.Editable = 'off';
            app.Status.Value = {'Idle'};
            app.dcmFolder = uieditfield(app.RightPanel, 'text');
            app.dcmFolder.Value = '\\NSWPRAPP02\RadCalc$\import\VIC\OrphanedImport\';
            app.TDSFolder = uieditfield(app.RightPanel, 'text');
            app.TDSFolder.Value = '\\rovprdae01\va_transfer\TDS\';
            app.Status_2 = uitextarea(app.RightPanel);
            app.Status_2.Editable = 'off';
            app.Status_2.Value = {'Idle'};
            app.PLANFILESFOUNDLabel = uilabel(app.RightPanel);
            app.PLANFILESFOUNDLabel.Text = 'PLAN FILES FOUND';
            app.TRAJECTORYFILESFOUNDLabel = uilabel(app.RightPanel);
            app.TRAJECTORYFILESFOUNDLabel.Text = 'TRAJECTORY FILES FOUND';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Date'; 'ID'; 'Plan'; 'Beam'; 'Gamma Pass Rate [%]'; 'Plan Path'; 'Log Path'};
            app.UITable.RowName = {};
            app.RESULTSLabel = uilabel(app.RightPanel);
            app.RESULTSLabel.Text = 'RESULTS';
            app.Lamp = uilamp(app.RightPanel);
            app.Lamp_2 = uilamp(app.RightPanel);
            app.OutputFolder = uieditfield(app.RightPanel, 'text');
            app.OutputFolder.Value = 'C:\Temp\';
            % Show the figure after all components are created
            app.shepherd.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShepherdScan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.shepherd)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.shepherd)
        end
    end
end